classdef PowerServoGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PowerServoUIFigure              matlab.ui.Figure
        PID2Panel                       matlab.ui.container.Panel
        ActuatorDropDown_2              matlab.ui.control.DropDown
        ActuatorDropDown_2Label         matlab.ui.control.Label
        ControlSpinner_2                matlab.ui.control.Spinner
        ControlSpinner_2Label           matlab.ui.control.Label
        KdSpinner_2                     matlab.ui.control.Spinner
        KdSpinner_2Label                matlab.ui.control.Label
        KiSpinner_2                     matlab.ui.control.Spinner
        KiSpinner_2Label                matlab.ui.control.Label
        PolaritySwitch_2                matlab.ui.control.Switch
        PolaritySwitch_2Label           matlab.ui.control.Label
        DivisorSpinner_2                matlab.ui.control.Spinner
        DivisorSpinner_2Label           matlab.ui.control.Label
        KpSpinner_2                     matlab.ui.control.Spinner
        KpSpinner_2Label                matlab.ui.control.Label
        EnableSwitch_2                  matlab.ui.control.Switch
        EnableSwitch_2Label             matlab.ui.control.Label
        MainPWMControlPanel             matlab.ui.container.Panel
        AO1Spinner                      matlab.ui.control.Spinner
        AO1Spinner_2Label               matlab.ui.control.Label
        AO1LimitsEditField              matlab.ui.control.EditField
        AO1LimitsEditFieldLabel         matlab.ui.control.Label
        AO0LimitsEditField              matlab.ui.control.EditField
        AO0LimitsEditFieldLabel         matlab.ui.control.Label
        AO0Spinner                      matlab.ui.control.Spinner
        AO0Spinner_2Label               matlab.ui.control.Label
        PID1Panel                       matlab.ui.container.Panel
        ActuatorDropDown                matlab.ui.control.DropDown
        ActuatorDropDownLabel           matlab.ui.control.Label
        ControlSpinner                  matlab.ui.control.Spinner
        ControlSpinnerLabel             matlab.ui.control.Label
        KdSpinner                       matlab.ui.control.Spinner
        KdSpinnerLabel                  matlab.ui.control.Label
        KiSpinner                       matlab.ui.control.Spinner
        KiSpinnerLabel                  matlab.ui.control.Label
        PolaritySwitch                  matlab.ui.control.Switch
        PolaritySwitchLabel             matlab.ui.control.Label
        DivisorSpinner                  matlab.ui.control.Spinner
        DivisorSpinnerLabel             matlab.ui.control.Label
        KpSpinner                       matlab.ui.control.Spinner
        KpSpinnerLabel                  matlab.ui.control.Label
        EnableSwitch                    matlab.ui.control.Switch
        EnableSwitchLabel               matlab.ui.control.Label
        MainDACControlPanel             matlab.ui.container.Panel
        OUT2Spinner                     matlab.ui.control.Spinner
        OUT2SpinnerLabel                matlab.ui.control.Label
        OUT2LimitsEditField             matlab.ui.control.EditField
        OUT2LimitsEditFieldLabel        matlab.ui.control.Label
        OUT1LimitsEditField             matlab.ui.control.EditField
        OUT1LimitsEditFieldLabel        matlab.ui.control.Label
        OUT1Spinner                     matlab.ui.control.Spinner
        OUT1SpinnerLabel                matlab.ui.control.Label
        ApplicationSettingsPanel        matlab.ui.container.Panel
        YLim2EditField                  matlab.ui.control.EditField
        YLim2EditFieldLabel             matlab.ui.control.Label
        AcquisitionSamplesEditField     matlab.ui.control.NumericEditField
        AcquisitionSamplesEditFieldLabel  matlab.ui.control.Label
        SaveButton                      matlab.ui.control.Button
        LoadButton                      matlab.ui.control.Button
        YLim1EditField                  matlab.ui.control.EditField
        YLim1Label                      matlab.ui.control.Label
        PlotUpdateTimemsEditField       matlab.ui.control.NumericEditField
        PlotUpdateTimemsEditFieldLabel  matlab.ui.control.Label
        FetchDataButton                 matlab.ui.control.Button
        AutoFetchDataSwitch             matlab.ui.control.Switch
        AutoFetchDataSwitchLabel        matlab.ui.control.Label
        FetchButton                     matlab.ui.control.Button
        UploadButton                    matlab.ui.control.Button
        AutoUpdateSwitch                matlab.ui.control.Switch
        AutoUpdateSwitchLabel           matlab.ui.control.Label
        AcquisitionSettingsPanel        matlab.ui.container.Panel
        FIFO2DropDown                   matlab.ui.control.DropDown
        FIFO2DropDownLabel              matlab.ui.control.Label
        FIFO1DropDown                   matlab.ui.control.DropDown
        FIFO1DropDownLabel              matlab.ui.control.Label
        FIFO2CheckBox                   matlab.ui.control.CheckBox
        FIFO1CheckBox                   matlab.ui.control.CheckBox
        Log2ofCICshiftSpinner           matlab.ui.control.Spinner
        Log2ofCICshiftSpinnerLabel      matlab.ui.control.Label
        BWHzEditField                   matlab.ui.control.NumericEditField
        BWHzEditFieldLabel              matlab.ui.control.Label
        dtsEditField                    matlab.ui.control.NumericEditField
        dtsEditFieldLabel               matlab.ui.control.Label
        Log2CICRateSpinner              matlab.ui.control.Spinner
        Log2CICRateSpinnerLabel         matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        device                           PowerServoControl % PowerServoControl control
        autoUpdate % Description
        int_timer % Description
        plotType % Description
    end
    
    methods (Access = public)
        
        function results = updateDevice(app)
            if strcmpi(app.AutoUpdateSwitch.Value,'On') && strcmpi(app.AutoFetchDataSwitch.Value,'Off')
                app.device.upload;
            end
            app.updateDisplay;
        end
        
        function results = updateDisplay(app)
            %
            % Shorten names
            %
            d = app.device;
            pids = d.pids;
            %
            % Main settings
            %
            app.Log2CICRateSpinner.Value = d.log2_rate.value;
            app.Log2ofCICshiftSpinner.Value = d.cic_shift.value;
            app.dtsEditField.Value = d.dt();
            app.BWHzEditField.Value = 1/(2*d.dt());
            %
            % DAC settings
            %
            app.OUT1Spinner.Value = d.dac(1).value;
            app.OUT2Spinner.Value = d.dac(2).value;
            app.OUT1LimitsEditField.Value = sprintf('%.1f,%.1f',d.dac_lower_limits(1).value,d.dac_upper_limits(1).value);
            app.OUT2LimitsEditField.Value = sprintf('%.1f,%.1f',d.dac_lower_limits(2).value,d.dac_upper_limits(2).value);
            %
            % PWM Settings
            %
            app.AO0Spinner.Value = d.pwm(1).value;
            app.AO1Spinner.Value = d.pwm(2).value;
            app.AO0LimitsEditField.Value = sprintf('%.1f,%.1f',d.pwm_lower_limits(1).value,d.pwm_upper_limits(1).value);
            app.AO1LimitsEditField.Value = sprintf('%.1f,%.1f',d.pwm_lower_limits(2).value,d.pwm_upper_limits(2).value);
            %
            % PID 1 settings
            %
            app.EnableSwitch.Value = app.convert_on_off(pids(1).enable.value);
            app.PolaritySwitch.Value = app.convert_polarity(pids(1).polarity.value);
            app.KpSpinner.Value = pids(1).Kp.value;
            app.KiSpinner.Value = pids(1).Ki.value;
            app.KdSpinner.Value = pids(1).Kd.value;
            app.DivisorSpinner.Value = pids(1).divisor.value;
            app.ControlSpinner.Value = pids(1).control.value;
            app.OutputswitchSwitch.Value = d.output_switch(1).value;
            %
            % PID 2 settings
            %
            app.EnableSwitch_2.Value = app.convert_on_off(pids(2).enable.value);
            app.PolaritySwitch_2.Value = app.convert_polarity(pids(2).polarity.value);
            app.KpSpinner_2.Value = pids(2).Kp.value;
            app.KiSpinner_2.Value = pids(2).Ki.value;
            app.KdSpinner_2.Value = pids(2).Kd.value;
            app.DivisorSpinner_2.Value = pids(2).divisor.value;
            app.ControlSpinner_2.Value = pids(2).control.value;
            app.OutputswitchSwitch_2.Value = d.output_switch(2).value;
            %
            % FIFO routing table
            %
            app.FIFO1DropDown.Value = d.fifo_route(1).value;
            app.FIFO2DropDown.Value = d.fifo_route(2).value;
        end
        
        function results = updatePlot(app)
            app.device.upload;
            app.device.getData(app.AcquisitionSamplesEditField.Value);
            if numel(app.device.t) ~= size(app.device.data,1)
%                 fprintf('Data acquisition sizes are wrong\n');
                return;
            end
            yyaxis(app.UIAxes,'left');
            cla(app.UIAxes);
            yyaxis(app.UIAxes,'right');
            cla(app.UIAxes);
            clr = {'#0072BD','#D95319','#EDB120','#7E2F8E'};
            for nn = 1:size(app.device.data,2)
                s = sprintf('FIFO%dCheckBox',nn);
                s2 = sprintf('FIFO%dDropDown',nn);
                if app.(s).Value
                    if ~app.convert_fifo_route(app.(s2).Value)
                        yyaxis(app.UIAxes,'left');
                        plot(app.UIAxes,app.device.t,app.device.data(:,nn),'.-','color',clr{nn});
                        hold(app.UIAxes,'on');
                    else
                        yyaxis(app.UIAxes,'right');
                        plot(app.UIAxes,app.device.t,app.device.data(:,nn)*app.device.CONV_PWM,'.-','color',clr{nn});
                        hold(app.UIAxes,'on');
                    end
                end
            end
            yyaxis(app.UIAxes,'left');
            hold(app.UIAxes,'off');
            app.UIAxes.XLabel.String = 'Time [s]';
            app.UIAxes.YLabel.String = 'Signal [V]';
            set(app.UIAxes,'XGrid','on','YGrid','on')
            lims1 = sscanf(app.YLim1EditField.Value,'%f,%f');
            if ~isempty(lims1)
                try
                    set(app.UIAxes,'YLim',sort(lims1));
                catch
                    
                end
            end
            
            yyaxis(app.UIAxes,'right');
            hold(app.UIAxes,'off');
            app.UIAxes.XLabel.String = 'Time [s]';
            app.UIAxes.YLabel.String = 'Signal [V]';
            set(app.UIAxes,'XGrid','on','YGrid','on')
            lims2 = sscanf(app.YLim2EditField.Value,'%f,%f');
            if ~isempty(lims2)
                try
                    set(app.UIAxes,'YLim',sort(lims2));
                catch
                    
                end
            end
        end
        
        
        function results = convert_fifo_route(app,v)
            if isnumeric(v)
                if v == 0
                    results = 'In';
                else
                    results = 'Out';
                end
            else
                if strcmpi(v,'In')
                    results = 0;
                elseif strcmpi(v,'Out')
                    results = 1;
                end
            end
        end

        function results = convert_on_off(app,v)
            if isnumeric(v)
                if v == 0
                    results = 'Off';
                else
                    results = 'On';
                end
            else
                if strcmpi(v,'Off')
                    results = 0;
                elseif strcmpi(v,'On')
                    results = 1;
                end
            end
        end

        function results = convert_polarity(app,v)
            if isnumeric(v)
                if v == 0
                    results = 'Negative';
                else
                    results = 'Positive';
                end
            else
                if strcmpi(v,'Negative')
                    results = 0;
                elseif strcmpi(v,'Positive')
                    results = 1;
                end
            end
        end

        function results = convert_output_switch(app,v)
            if isnumeric(v)
                if v == 0
                    results = 'PWM';
                else
                    results = 'DAC';
                end
            else
                if strcmpi(v,'PWM')
                    results = 0;
                elseif strcmpi(v,'DAC')
                    results = 1;
                end
            end
        end
        
        function set_timer(app,value)
            if strcmpi(value,'off')
                if isa(app.int_timer,'timer')
                    stop(app.int_timer);
                    delete(app.int_timer);
                    app.int_timer = [];
                end
                app.AutoFetchDataSwitch.Value = 'Off';
            elseif strcmpi(value,'on')
                app.int_timer = timer('ExecutionMode','fixedSpacing','Name','Data fetch timer','Period',app.PlotUpdateTimemsEditField.Value/1e3);
                app.int_timer.ErrorFcn = @(obj,event) delete(obj);
                app.int_timer.TimerFcn = @(~,~) app.updatePlot;
                start(app.int_timer);
                app.AutoFetchDataSwitch.Value = 'On';
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            if nargin < 2
                app.device = PowerServoControl;
            elseif isa(varargin{1},'PowerServoControl')
                app.device = varargin{1};
            elseif ischar(varargin{1}) || isstring(varargin{1})
                if numel(varargin) == 1
                    app.device = PowerServoControl(varargin{1});
                else
                    app.device = PowerServoControl(varargin{1},varargin{2});
                end
                app.device.setDefaults;
            else
                error('Input arguments must be either a ''PowerServoControl'' object or an IP address and port number!');
            end
            
            if nargin == 2 && ~isa(varargin{1},'Device')
                try
                    app.device.conn.open;
                    app.device.fetch;
                catch ME
                    warning('A connection to the socket server could not be established!');
                end
            end

            app.FIFO1DropDown.Items = PowerServoControl.FIFO_ROUTE_OPTIONS;
            app.FIFO2DropDown.Items = PowerServoControl.FIFO_ROUTE_OPTIONS;
            
            app.updateDisplay;
            warning('off','MATLAB:callback:error');
            
        end

        % Value changed function: Log2CICRateSpinner
        function Log2CICRateSpinnerValueChanged(app, event)
            value = app.Log2CICRateSpinner.Value;
            app.device.log2_rate.set(value);
            app.updateDevice;
        end

        % Value changed function: EnableSwitch
        function EnableSwitchValueChanged(app, event)
           value = app.EnableSwitch.Value;
           app.device.pids(1).enable.set(app.convert_on_off(value));
            app.updateDevice;
        end

        % Value changed function: AutoUpdateSwitch
        function AutoUpdateSwitchValueChanged(app, event)
            value = app.AutoUpdateSwitch.Value;
            if strcmpi(value,'off')
                app.autoUpdate = 0;
            else
                app.autoUpdate = 1;
            end
        end

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
            app.device.upload;
        end

        % Button pushed function: FetchButton
        function FetchButtonPushed(app, event)
            app.device.fetch;
            app.updateDisplay;
        end

        % Button pushed function: FetchDataButton
        function FetchDataButtonPushed(app, event)
            app.updatePlot;
        end

        % Value changed function: AutoFetchDataSwitch
        function AutoFetchDataSwitchValueChanged(app, event)
            value = app.AutoFetchDataSwitch.Value;
            app.set_timer(value);
        end

        % Value changed function: DivisorSpinner
        function DivisorSpinnerValueChanged(app, event)
            value = app.DivisorSpinner.Value;
            app.device.pids(1).divisor(1).set(value);
            app.updateDevice;
        end

        % Value changed function: PlotUpdateTimemsEditField
        function PlotUpdateTimemsEditFieldValueChanged(app, event)
            value = app.PlotUpdateTimemsEditField.Value;
            if isa(app.int_timer,'timer') && isvalid(app.int_timer)
                stop(app.int_timer);
                app.int_timer.Period = value/1e3;
                start(app.int_timer);
            end
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [file,path] = uigetfile;
            data = load([path,file],'saved_device');
            app.device.loadstruct(data.saved_device);
            app.updateDevice;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            saved_device = app.device.struct;
            uisave('saved_device','servo-parameters');
        end

        % Value changed function: Log2ofCICshiftSpinner
        function Log2ofCICshiftSpinnerValueChanged(app, event)
            value = app.Log2ofCICshiftSpinner.Value;
            app.device.cic_shift.set(value);
            app.updateDevice;
        end

        % Value changed function: OUT1Spinner
        function OUT1SpinnerValueChanged(app, event)
            value = app.OUT1Spinner.Value;
            app.device.dac(1).set(value);
            app.updateDevice;
        end

        % Value changed function: FIFO1DropDown
        function FIFO1DropDownValueChanged(app, event)
            value = app.FIFO1DropDown.Value;
            app.device.fifo_route(1).set(value);
            app.updateDevice;
        end

        % Value changed function: FIFO2DropDown
        function FIFO2DropDownValueChanged(app, event)
            value = app.FIFO2DropDown.Value;
            app.device.fifo_route(2).set(value);
            app.updateDevice;
        end

        % Value changed function: PolaritySwitch
        function PolaritySwitchValueChanged(app, event)
            value = app.PolaritySwitch.Value;
            app.device.pids(1).polarity.set(app.convert_polarity(value));
            app.updateDevice;
        end

        % Value changed function: KpSpinner
        function KpSpinnerValueChanged(app, event)
            value = app.KpSpinner.Value;
            app.device.pids(1).Kp.set(value);
            app.updateDevice;
        end

        % Value changed function: OUT2Spinner
        function OUT2SpinnerValueChanged(app, event)
            value = app.OUT2Spinner.Value;
            app.device.dac(2).set(value);
            app.updateDevice;
        end

        % Value changed function: OUT1LimitsEditField
        function OUT1LimitsEditFieldValueChanged(app, event)
            value = app.OUT1LimitsEditField.Value;
            lims = sscanf(value,'%f,%f');
            if ~isempty(lims)
                try
                    lims = sort(lims);
                    app.device.dac_lower_limits(1).set(lims(1));
                    app.device.dac_upper_limits(1).set(lims(2));
                    app.updateDevice;
                catch
                    
                end
            end
        end

        % Value changed function: OUT2LimitsEditField
        function OUT2LimitsEditFieldValueChanged(app, event)
            value = app.OUT2LimitsEditField.Value;
            lims = sscanf(value,'%f,%f');
            if ~isempty(lims)
                try
                    lims = sort(lims);
                    app.device.dac_lower_limits(2).set(lims(1));
                    app.device.dac_upper_limits(2).set(lims(2));
                    app.updateDevice;
                catch
                    
                end
            end
        end

        % Value changed function: AO0LimitsEditField
        function AO0LimitsEditFieldValueChanged(app, event)
            value = app.AO0LimitsEditField.Value;
            lims = sscanf(value,'%f,%f');
            if ~isempty(lims)
                try
                    lims = sort(lims);
                    app.device.pwm_lower_limits(1).set(lims(1));
                    app.device.pwm_upper_limits(1).set(lims(2));
                    app.updateDevice;
                catch
                    
                end
            end
        end

        % Value changed function: AO1LimitsEditField
        function AO1LimitsEditFieldValueChanged(app, event)
            value = app.AO1LimitsEditField.Value;
            lims = sscanf(value,'%f,%f');
            if ~isempty(lims)
                try
                    lims = sort(lims);
                    app.device.pwm_lower_limits(2).set(lims(1));
                    app.device.pwm_upper_limits(2).set(lims(2));
                    app.updateDevice;
                catch
                    
                end
            end
        end

        % Value changed function: AO0Spinner
        function AO0SpinnerValueChanged(app, event)
            value = app.AO0Spinner.Value;
            app.device.pwm(1).set(value);
            app.updateDevice;
        end

        % Value changed function: AO1Spinner
        function AO1SpinnerValueChanged(app, event)
            value = app.AO1Spinner.Value;
            app.device.pwm(2).set(value);
            app.updateDevice;
        end

        % Value changed function: PolaritySwitch_2
        function PolaritySwitch_2ValueChanged(app, event)
            value = app.PolaritySwitch_2.Value;
            app.device.pids(2).polarity.set(app.convert_polarity(value));
            app.updateDevice;
        end

        % Value changed function: EnableSwitch_2
        function EnableSwitch_2ValueChanged(app, event)
            value = app.EnableSwitch_2.Value;
            app.device.pids(2).enable.set(app.convert_on_off(value));
            app.updateDevice;
        end

        % Value changed function: KiSpinner
        function KiSpinnerValueChanged(app, event)
            value = app.KiSpinner.Value;
            app.device.pids(1).Ki.set(value);
            app.updateDevice;
        end

        % Value changed function: KdSpinner
        function KdSpinnerValueChanged(app, event)
            value = app.KdSpinner.Value;
            app.device.pids(1).Kd.set(value);
            app.updateDevice;
        end

        % Value changed function: KpSpinner_2
        function KpSpinner_2ValueChanged(app, event)
            value = app.KpSpinner_2.Value;
            app.device.pids(2).Kp.set(value);
            app.updateDevice;
        end

        % Value changed function: KiSpinner_2
        function KiSpinner_2ValueChanged(app, event)
            value = app.KiSpinner_2.Value;
            app.device.pids(2).Ki.set(value);
            app.updateDevice;
        end

        % Value changed function: KdSpinner_2
        function KdSpinner_2ValueChanged(app, event)
            value = app.KdSpinner_2.Value;
            app.device.pids(2).Kdp.set(value);
            app.updateDevice;
        end

        % Value changed function: DivisorSpinner_2
        function DivisorSpinner_2ValueChanged(app, event)
            value = app.DivisorSpinner_2.Value;
            app.device.pids(2).divisor.set(value);
            app.updateDevice;
        end

        % Value changed function: ControlSpinner
        function ControlSpinnerValueChanged(app, event)
            value = app.ControlSpinner.Value;
            app.device.pids(1).control.set(value);
            app.updateDevice;
        end

        % Value changed function: ControlSpinner_2
        function ControlSpinner_2ValueChanged(app, event)
            value = app.ControlSpinner_2.Value;
            app.device.pids(2).control.set(value);
            app.updateDevice;
        end

        % Callback function
        function OutputswitchSwitchValueChanged(app, event)
            value = app.OutputswitchSwitch.Value;
            app.device.output_switch(1).set(app.convert_output_switch(value));
            app.updateDevice;
        end

        % Callback function
        function OutputswitchSwitch_2ValueChanged(app, event)
            value = app.OutputswitchSwitch_2.Value;
            app.device.output_switch(2).set(app.convert_output_switch(value));
            app.updateDevice;
        end

        % Value changed function: ActuatorDropDown
        function ActuatorDropDownValueChanged(app, event)
            value = app.ActuatorDropDown.Value;
            app.device.output_switch(1).set(value);
            app.updateDevice;
        end

        % Value changed function: ActuatorDropDown_2
        function ActuatorDropDown_2ValueChanged(app, event)
            value = app.ActuatorDropDown_2.Value;
            app.device.output_switch(2).set(value);
            app.updateDevice;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PowerServoUIFigure = uifigure('Visible', 'off');
            app.PowerServoUIFigure.Name = 'Power Servo Control';
            app.UIAxes = uiaxes(app.PowerServoUIFigure);
            title(app.UIAxes, 'Measurements')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.AcquisitionSettingsPanel = uipanel(app.PowerServoUIFigure);
            app.AcquisitionSettingsPanel.Title = 'Acquisition Settings';
            app.Log2CICRateSpinnerLabel = uilabel(app.AcquisitionSettingsPanel);
            app.Log2CICRateSpinnerLabel.Text = 'Log2(CIC Rate)';
            app.Log2CICRateSpinner = uispinner(app.AcquisitionSettingsPanel);
            app.Log2CICRateSpinner.Limits = [2 13];
            app.Log2CICRateSpinner.ValueChangedFcn = createCallbackFcn(app, @Log2CICRateSpinnerValueChanged, true);
            app.Log2CICRateSpinner.Value = 13;
            app.dtsEditFieldLabel = uilabel(app.AcquisitionSettingsPanel);
            app.dtsEditFieldLabel.Text = 'dt [s]';
            app.dtsEditField = uieditfield(app.AcquisitionSettingsPanel, 'numeric');
            app.dtsEditField.ValueDisplayFormat = '%.2e';
            app.dtsEditField.Editable = 'off';
            app.BWHzEditFieldLabel = uilabel(app.AcquisitionSettingsPanel);
            app.BWHzEditFieldLabel.Text = 'BW [Hz]';
            app.BWHzEditField = uieditfield(app.AcquisitionSettingsPanel, 'numeric');
            app.BWHzEditField.ValueDisplayFormat = '%.2e';
            app.BWHzEditField.Editable = 'off';
            app.Log2ofCICshiftSpinnerLabel = uilabel(app.AcquisitionSettingsPanel);
            app.Log2ofCICshiftSpinnerLabel.Text = 'Log2 of CIC shift';
            app.Log2ofCICshiftSpinner = uispinner(app.AcquisitionSettingsPanel);
            app.Log2ofCICshiftSpinner.Limits = [-100 100];
            app.Log2ofCICshiftSpinner.ValueChangedFcn = createCallbackFcn(app, @Log2ofCICshiftSpinnerValueChanged, true);
            app.FIFO1CheckBox = uicheckbox(app.AcquisitionSettingsPanel);
            app.FIFO1CheckBox.Text = 'FIFO1';
            app.FIFO1CheckBox.Value = true;
            app.FIFO2CheckBox = uicheckbox(app.AcquisitionSettingsPanel);
            app.FIFO2CheckBox.Text = 'FIFO2';
            app.FIFO2CheckBox.Value = true;
            app.FIFO1DropDownLabel = uilabel(app.AcquisitionSettingsPanel);
            app.FIFO1DropDownLabel.Text = 'FIFO 1';
            app.FIFO1DropDown = uidropdown(app.AcquisitionSettingsPanel);
            app.FIFO1DropDown.Items = {'IN1', 'IN2'};
            app.FIFO1DropDown.ValueChangedFcn = createCallbackFcn(app, @FIFO1DropDownValueChanged, true);
            app.FIFO1DropDown.Value = 'IN1';
            app.FIFO2DropDownLabel = uilabel(app.AcquisitionSettingsPanel);
            app.FIFO2DropDownLabel.Text = 'FIFO 2';
            app.FIFO2DropDown = uidropdown(app.AcquisitionSettingsPanel);
            app.FIFO2DropDown.Items = {'IN1', 'IN2'};
            app.FIFO2DropDown.ValueChangedFcn = createCallbackFcn(app, @FIFO2DropDownValueChanged, true);
            app.FIFO2DropDown.Value = 'IN1';
            app.ApplicationSettingsPanel = uipanel(app.PowerServoUIFigure);
            app.ApplicationSettingsPanel.Title = 'Application Settings';
            app.AutoUpdateSwitchLabel = uilabel(app.ApplicationSettingsPanel);
            app.AutoUpdateSwitchLabel.Text = 'Auto-Update';
            app.AutoUpdateSwitch = uiswitch(app.ApplicationSettingsPanel, 'slider');
            app.AutoUpdateSwitch.ValueChangedFcn = createCallbackFcn(app, @AutoUpdateSwitchValueChanged, true);
            app.UploadButton = uibutton(app.ApplicationSettingsPanel, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Text = 'Upload';
            app.FetchButton = uibutton(app.ApplicationSettingsPanel, 'push');
            app.FetchButton.ButtonPushedFcn = createCallbackFcn(app, @FetchButtonPushed, true);
            app.FetchButton.Text = 'Fetch';
            app.AutoFetchDataSwitchLabel = uilabel(app.ApplicationSettingsPanel);
            app.AutoFetchDataSwitchLabel.Text = 'Auto-Fetch Data';
            app.AutoFetchDataSwitch = uiswitch(app.ApplicationSettingsPanel, 'slider');
            app.AutoFetchDataSwitch.ValueChangedFcn = createCallbackFcn(app, @AutoFetchDataSwitchValueChanged, true);
            app.FetchDataButton = uibutton(app.ApplicationSettingsPanel, 'push');
            app.FetchDataButton.ButtonPushedFcn = createCallbackFcn(app, @FetchDataButtonPushed, true);
            app.FetchDataButton.Text = 'Fetch Data';
            app.PlotUpdateTimemsEditFieldLabel = uilabel(app.ApplicationSettingsPanel);
            app.PlotUpdateTimemsEditFieldLabel.Text = 'Plot Update Time [ms]';
            app.PlotUpdateTimemsEditField = uieditfield(app.ApplicationSettingsPanel, 'numeric');
            app.PlotUpdateTimemsEditField.Limits = [0 Inf];
            app.PlotUpdateTimemsEditField.ValueDisplayFormat = '%4.2g';
            app.PlotUpdateTimemsEditField.ValueChangedFcn = createCallbackFcn(app, @PlotUpdateTimemsEditFieldValueChanged, true);
            app.PlotUpdateTimemsEditField.Value = 10;
            app.YLim1Label = uilabel(app.ApplicationSettingsPanel);
            app.YLim1Label.Text = 'YLim1';
            app.YLim1EditField = uieditfield(app.ApplicationSettingsPanel, 'text');
            app.YLim1EditField.Value = '0,1';
            app.LoadButton = uibutton(app.ApplicationSettingsPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.SaveButton = uibutton(app.ApplicationSettingsPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.AcquisitionSamplesEditFieldLabel = uilabel(app.ApplicationSettingsPanel);
            app.AcquisitionSamplesEditFieldLabel.Text = 'Acquisition Samples';
            app.AcquisitionSamplesEditField = uieditfield(app.ApplicationSettingsPanel, 'numeric');
            app.AcquisitionSamplesEditField.Limits = [0 Inf];
            app.AcquisitionSamplesEditField.ValueDisplayFormat = '%.0f';
            app.AcquisitionSamplesEditField.Value = 1000;
            app.YLim2EditFieldLabel = uilabel(app.ApplicationSettingsPanel);
            app.YLim2EditFieldLabel.Text = 'YLim2';
            app.YLim2EditField = uieditfield(app.ApplicationSettingsPanel, 'text');
            app.YLim2EditField.Value = '0,1';
            app.MainDACControlPanel = uipanel(app.PowerServoUIFigure);
            app.MainDACControlPanel.Title = 'Main DAC Control';
            app.OUT1SpinnerLabel = uilabel(app.MainDACControlPanel);
            app.OUT1SpinnerLabel.Text = 'OUT1';
            app.OUT1Spinner = uispinner(app.MainDACControlPanel);
            app.OUT1Spinner.Step = 0.01;
            app.OUT1Spinner.Limits = [-1 1];
            app.OUT1Spinner.ValueDisplayFormat = '%11.2g';
            app.OUT1Spinner.ValueChangedFcn = createCallbackFcn(app, @OUT1SpinnerValueChanged, true);
            app.OUT1LimitsEditFieldLabel = uilabel(app.MainDACControlPanel);
            app.OUT1LimitsEditFieldLabel.Text = 'OUT1 Limits';
            app.OUT1LimitsEditField = uieditfield(app.MainDACControlPanel, 'text');
            app.OUT1LimitsEditField.ValueChangedFcn = createCallbackFcn(app, @OUT1LimitsEditFieldValueChanged, true);
            app.OUT1LimitsEditField.Value = '0,0.25';
            app.OUT2LimitsEditFieldLabel = uilabel(app.MainDACControlPanel);
            app.OUT2LimitsEditFieldLabel.Text = 'OUT2 Limits';
            app.OUT2LimitsEditField = uieditfield(app.MainDACControlPanel, 'text');
            app.OUT2LimitsEditField.ValueChangedFcn = createCallbackFcn(app, @OUT2LimitsEditFieldValueChanged, true);
            app.OUT2LimitsEditField.Value = '0,0.25';
            app.OUT2SpinnerLabel = uilabel(app.MainDACControlPanel);
            app.OUT2SpinnerLabel.Text = 'OUT2';
            app.OUT2Spinner = uispinner(app.MainDACControlPanel);
            app.OUT2Spinner.Step = 0.01;
            app.OUT2Spinner.Limits = [-1 1];
            app.OUT2Spinner.ValueDisplayFormat = '%11.2g';
            app.OUT2Spinner.ValueChangedFcn = createCallbackFcn(app, @OUT2SpinnerValueChanged, true);
            app.PID1Panel = uipanel(app.PowerServoUIFigure);
            app.PID1Panel.Title = 'PID 1';
            app.EnableSwitchLabel = uilabel(app.PID1Panel);
            app.EnableSwitchLabel.Text = 'Enable';
            app.EnableSwitch = uiswitch(app.PID1Panel, 'slider');
            app.EnableSwitch.ValueChangedFcn = createCallbackFcn(app, @EnableSwitchValueChanged, true);
            app.KpSpinnerLabel = uilabel(app.PID1Panel);
            app.KpSpinnerLabel.Text = 'Kp';
            app.KpSpinner = uispinner(app.PID1Panel);
            app.KpSpinner.Limits = [0 255];
            app.KpSpinner.RoundFractionalValues = 'on';
            app.KpSpinner.ValueDisplayFormat = '%d';
            app.KpSpinner.ValueChangedFcn = createCallbackFcn(app, @KpSpinnerValueChanged, true);
            app.DivisorSpinnerLabel = uilabel(app.PID1Panel);
            app.DivisorSpinnerLabel.Text = 'Divisor';
            app.DivisorSpinner = uispinner(app.PID1Panel);
            app.DivisorSpinner.Limits = [0 255];
            app.DivisorSpinner.RoundFractionalValues = 'on';
            app.DivisorSpinner.ValueDisplayFormat = '%d';
            app.DivisorSpinner.ValueChangedFcn = createCallbackFcn(app, @DivisorSpinnerValueChanged, true);
            app.PolaritySwitchLabel = uilabel(app.PID1Panel);
            app.PolaritySwitchLabel.Text = 'Polarity';
            app.PolaritySwitch = uiswitch(app.PID1Panel, 'slider');
            app.PolaritySwitch.Items = {'Negative', 'Positive'};
            app.PolaritySwitch.ValueChangedFcn = createCallbackFcn(app, @PolaritySwitchValueChanged, true);
            app.PolaritySwitch.Value = 'Negative';
            app.KiSpinnerLabel = uilabel(app.PID1Panel);
            app.KiSpinnerLabel.Text = 'Ki';
            app.KiSpinner = uispinner(app.PID1Panel);
            app.KiSpinner.Limits = [0 255];
            app.KiSpinner.RoundFractionalValues = 'on';
            app.KiSpinner.ValueDisplayFormat = '%d';
            app.KiSpinner.ValueChangedFcn = createCallbackFcn(app, @KiSpinnerValueChanged, true);
            app.KdSpinnerLabel = uilabel(app.PID1Panel);
            app.KdSpinnerLabel.Text = 'Kd';
            app.KdSpinner = uispinner(app.PID1Panel);
            app.KdSpinner.Limits = [0 255];
            app.KdSpinner.RoundFractionalValues = 'on';
            app.KdSpinner.ValueDisplayFormat = '%d';
            app.KdSpinner.ValueChangedFcn = createCallbackFcn(app, @KdSpinnerValueChanged, true);
            app.ControlSpinnerLabel = uilabel(app.PID1Panel);
            app.ControlSpinnerLabel.Text = 'Control';
            app.ControlSpinner = uispinner(app.PID1Panel);
            app.ControlSpinner.Step = 0.1;
            app.ControlSpinner.Limits = [-1 1];
            app.ControlSpinner.ValueChangedFcn = createCallbackFcn(app, @ControlSpinnerValueChanged, true);
            app.ActuatorDropDownLabel = uilabel(app.PID1Panel);
            app.ActuatorDropDownLabel.Text = 'Actuator';
            app.ActuatorDropDown = uidropdown(app.PID1Panel);
            app.ActuatorDropDown.Items = {'OUT1', 'OUT2'};
            app.ActuatorDropDown.ValueChangedFcn = createCallbackFcn(app, @ActuatorDropDownValueChanged, true);
            app.ActuatorDropDown.Value = 'OUT1';
            app.MainPWMControlPanel = uipanel(app.PowerServoUIFigure);
            app.MainPWMControlPanel.Title = 'Main PWM Control';
            app.AO0Spinner_2Label = uilabel(app.MainPWMControlPanel);
            app.AO0Spinner_2Label.Text = 'AO0';
            app.AO0Spinner = uispinner(app.MainPWMControlPanel);
            app.AO0Spinner.Step = 0.01;
            app.AO0Spinner.Limits = [0 1.6];
            app.AO0Spinner.ValueDisplayFormat = '%11.2g';
            app.AO0Spinner.ValueChangedFcn = createCallbackFcn(app, @AO0SpinnerValueChanged, true);
            app.AO0LimitsEditFieldLabel = uilabel(app.MainPWMControlPanel);
            app.AO0LimitsEditFieldLabel.Text = 'AO0 Limits';
            app.AO0LimitsEditField = uieditfield(app.MainPWMControlPanel, 'text');
            app.AO0LimitsEditField.ValueChangedFcn = createCallbackFcn(app, @AO0LimitsEditFieldValueChanged, true);
            app.AO0LimitsEditField.Value = '0,0.1';
            app.AO1LimitsEditFieldLabel = uilabel(app.MainPWMControlPanel);
            app.AO1LimitsEditFieldLabel.Text = 'AO1 Limits';
            app.AO1LimitsEditField = uieditfield(app.MainPWMControlPanel, 'text');
            app.AO1LimitsEditField.ValueChangedFcn = createCallbackFcn(app, @AO1LimitsEditFieldValueChanged, true);
            app.AO1LimitsEditField.Value = '0,0.1';
            app.AO1Spinner_2Label = uilabel(app.MainPWMControlPanel);
            app.AO1Spinner_2Label.Text = 'AO1';
            app.AO1Spinner = uispinner(app.MainPWMControlPanel);
            app.AO1Spinner.Step = 0.01;
            app.AO1Spinner.Limits = [0 1.6];
            app.AO1Spinner.ValueDisplayFormat = '%11.2g';
            app.AO1Spinner.ValueChangedFcn = createCallbackFcn(app, @AO1SpinnerValueChanged, true);
            app.PID2Panel = uipanel(app.PowerServoUIFigure);
            app.PID2Panel.Title = 'PID 2';
            app.EnableSwitch_2Label = uilabel(app.PID2Panel);
            app.EnableSwitch_2Label.Text = 'Enable';
            app.EnableSwitch_2 = uiswitch(app.PID2Panel, 'slider');
            app.EnableSwitch_2.ValueChangedFcn = createCallbackFcn(app, @EnableSwitch_2ValueChanged, true);
            app.KpSpinner_2Label = uilabel(app.PID2Panel);
            app.KpSpinner_2Label.Text = 'Kp';
            app.KpSpinner_2 = uispinner(app.PID2Panel);
            app.KpSpinner_2.Limits = [0 255];
            app.KpSpinner_2.RoundFractionalValues = 'on';
            app.KpSpinner_2.ValueDisplayFormat = '%d';
            app.KpSpinner_2.ValueChangedFcn = createCallbackFcn(app, @KpSpinner_2ValueChanged, true);
            app.DivisorSpinner_2Label = uilabel(app.PID2Panel);
            app.DivisorSpinner_2Label.Text = 'Divisor';
            app.DivisorSpinner_2 = uispinner(app.PID2Panel);
            app.DivisorSpinner_2.Limits = [0 255];
            app.DivisorSpinner_2.RoundFractionalValues = 'on';
            app.DivisorSpinner_2.ValueDisplayFormat = '%d';
            app.DivisorSpinner_2.ValueChangedFcn = createCallbackFcn(app, @DivisorSpinner_2ValueChanged, true);
            app.PolaritySwitch_2Label = uilabel(app.PID2Panel);
            app.PolaritySwitch_2Label.Text = 'Polarity';
            app.PolaritySwitch_2 = uiswitch(app.PID2Panel, 'slider');
            app.PolaritySwitch_2.Items = {'Negative', 'Positive'};
            app.PolaritySwitch_2.ValueChangedFcn = createCallbackFcn(app, @PolaritySwitch_2ValueChanged, true);
            app.PolaritySwitch_2.Value = 'Negative';
            app.KiSpinner_2Label = uilabel(app.PID2Panel);
            app.KiSpinner_2Label.Text = 'Ki';
            app.KiSpinner_2 = uispinner(app.PID2Panel);
            app.KiSpinner_2.Limits = [0 255];
            app.KiSpinner_2.RoundFractionalValues = 'on';
            app.KiSpinner_2.ValueDisplayFormat = '%d';
            app.KiSpinner_2.ValueChangedFcn = createCallbackFcn(app, @KiSpinner_2ValueChanged, true);
            app.KdSpinner_2Label = uilabel(app.PID2Panel);
            app.KdSpinner_2Label.Text = 'Kd';
            app.KdSpinner_2 = uispinner(app.PID2Panel);
            app.KdSpinner_2.Limits = [0 255];
            app.KdSpinner_2.RoundFractionalValues = 'on';
            app.KdSpinner_2.ValueDisplayFormat = '%d';
            app.KdSpinner_2.ValueChangedFcn = createCallbackFcn(app, @KdSpinner_2ValueChanged, true);
            app.ControlSpinner_2Label = uilabel(app.PID2Panel);
            app.ControlSpinner_2Label.Text = 'Control';
            app.ControlSpinner_2 = uispinner(app.PID2Panel);
            app.ControlSpinner_2.Step = 0.1;
            app.ControlSpinner_2.Limits = [-1 1];
            app.ControlSpinner_2.ValueChangedFcn = createCallbackFcn(app, @ControlSpinner_2ValueChanged, true);
            app.ActuatorDropDown_2Label = uilabel(app.PID2Panel);
            app.ActuatorDropDown_2Label.Text = 'Actuator';
            app.ActuatorDropDown_2 = uidropdown(app.PID2Panel);
            app.ActuatorDropDown_2.Items = {'OUT1', 'OUT2'};
            app.ActuatorDropDown_2.ValueChangedFcn = createCallbackFcn(app, @ActuatorDropDown_2ValueChanged, true);
            app.ActuatorDropDown_2.Value = 'OUT1';
            % Show the figure after all components are created
            app.PowerServoUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PowerServoGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PowerServoUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PowerServoUIFigure)
        end
    end
end